classdef SignalDetector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalDetector_v1     matlab.ui.Figure
        S1                    matlab.ui.control.Switch
        L1                    matlab.ui.control.Lamp
        L2                    matlab.ui.control.Lamp
        S2                    matlab.ui.control.Switch
        L3                    matlab.ui.control.Lamp
        S3                    matlab.ui.control.Switch
        L4                    matlab.ui.control.Lamp
        S4                    matlab.ui.control.Switch
        L5                    matlab.ui.control.Lamp
        S5                    matlab.ui.control.Switch
        L6                    matlab.ui.control.Lamp
        S6                    matlab.ui.control.Switch
        L7                    matlab.ui.control.Lamp
        S7                    matlab.ui.control.Switch
        L8                    matlab.ui.control.Lamp
        S8                    matlab.ui.control.Switch
        L9                    matlab.ui.control.Lamp
        S9                    matlab.ui.control.Switch
        UIAxes                matlab.ui.control.UIAxes
        ModeButtonGroup       matlab.ui.container.ButtonGroup
        SemiButton            matlab.ui.control.RadioButton
        AutoButton            matlab.ui.control.RadioButton
        ManualButton          matlab.ui.control.RadioButton
        TCPCtrl               matlab.ui.control.ToggleSwitch
        TCPControlLabel       matlab.ui.control.Label
        ClearTCPBufferButton  matlab.ui.control.Button
        OKButton              matlab.ui.control.Button
        StartButton           matlab.ui.control.StateButton
        NewLampLabel          matlab.ui.control.Label
        NewLamp               matlab.ui.control.Lamp
        XLabel                matlab.ui.control.Label
        All0Button            matlab.ui.control.Button
        All1Button            matlab.ui.control.Button
        OutputEditFieldLabel  matlab.ui.control.Label
        OutputEditField       matlab.ui.control.EditField
    end

    
    properties (Access = public)
        LampGroup % L1 - L9
        SwitchGroup % S1 - S9
        
        t % tcp object
        
        mag % 1 16X; 0 40X
        
        fNext = 0;
    end
    
    properties (Access = private)
        LampColors = [0.8,0.8,0.8;0.39,0.83,0.07];

        re16 = 0.75576;
        re40 = 0.27361;
        
        olp40 = 0.1;
        olp16
        z0
        z1
        z2
        
        im16
        im40
        
        axPosition;
        
        output;
    end
    
    methods (Access = private)
        % about ui ctrl
        function groupLamp(app)
            app.LampGroup = [app.L1,app.L2,app.L3,...
                app.L4,app.L5,app.L6,...
                app.L7,app.L8,app.L9];
        end
    
        function v = groupSwitchValue(app)
            v = [app.S1.Value,app.S2.Value,app.S3.Value,...
                app.S4.Value,app.S5.Value,app.S6.Value,...
                app.S7.Value,app.S8.Value,app.S9.Value];
        end
    
        function c = changeLampColor(app,v)
            c = app.LampColors(str2double(v)+1,:);
        end
        
        function swtichAvi(app,onoff)
            individualCtrl(app.L1);
            individualCtrl(app.S1);
            
            individualCtrl(app.L2);
            individualCtrl(app.S2);
            
            individualCtrl(app.L3);
            individualCtrl(app.S3);
            
            individualCtrl(app.L4);
            individualCtrl(app.S4);
            
            individualCtrl(app.L5);
            individualCtrl(app.S5);
            
            individualCtrl(app.L6);
            individualCtrl(app.S6);
            
            individualCtrl(app.L7);
            individualCtrl(app.S7);
            
            individualCtrl(app.L9);
            individualCtrl(app.S9);
            
            function individualCtrl(uiObj)
                uiObj.Enable = onoff;
            end
        end
        
        function changeSwitchsValue16(app,result)
            % result is char
            app.S1.Value = result(1);
            app.S1ValueChanged();
            
            app.S2.Value = result(2);
            app.S2ValueChanged();
            
            app.S3.Value = result(3);
            app.S3ValueChanged();
            
            app.S4.Value = result(4);
            app.S4ValueChanged();
            
            app.S5.Value = result(5);
            app.S5ValueChanged();
            
            app.S6.Value = result(6);
            app.S6ValueChanged();
            
            app.S7.Value = result(7);
            app.S7ValueChanged();
            
            app.S8.Value = result(8);
            app.S8ValueChanged();
            
            app.S9.Value = result(9);
            app.S9ValueChanged();
        end
        
        function changeSwitchsValue40(app,result)
            app.S8.Value = result;
            app.S8ValueChanged();
        end
    
        function turnonNewImLamp(app)
            app.NewLamp.Color = app.LampColors(2,:);
        end
        
        function turnoffNewImLamp(app)
            app.NewLamp.Color = app.LampColors(1,:);
        end
    
        function mode = getMode(app)
            selectedButton = app.ModeButtonGroup.SelectedObject;
            mode = lower(selectedButton.Text);
        end
    
        function enableOKButton(app,onoff)
            app.OKButton.Enable = onoff;
        end
    end
    
    methods (Access = private)
        % about process related
        
        function getZ(app)
            app.olp16 = 1 - 3 * ( 1 - app.olp40) * app.re40 / app.re16;
            app.z0 = round(app.re40 *1200 / app.re16);
            app.z1 = round((0.5 * app.re40 + ...
                (app.olp40 - 1) * app.re40 + 0.5 * app.re16) * 1200 / app.re16);
            app.z2 = round(app.olp40 * app.re40 *1200 / app.re16);
        end
    
        function getIm16(app)
            im_1 = fread(app.t,1200*1200,'uint16');
            app.im16 = uint16(reshape(im_1',[1200,1200]));
        end
        
        function getIm40(app)
            im_1 = fread(app.t,512*512,'uint16');
            app.im40 = uint16(reshape(im_1',[512,512]));
        end
        
        function display16(app)
            beep2;
            montage(repmat(reshape(...
                app.imSegment(app.im16),512,512,1,9),1,1,3,1),...
                'Parent',app.UIAxes);
            app.UIAxes.Position= app.axPosition;
        end
        
        function display40(app)
            beep2;
            imshow(imadjust_(app.im40),'Parent',app.UIAxes);
            app.UIAxes.Position= app.axPosition;
        end
        
        function waitForOK(app)
            waitfor(app,'fNext',1);
            app.fNext = 0;
        end
        
        function ims = imSegment(app,img)
            ims = zeros(512,512,9,'uint16');
                        
            ims(:,:,1) = imresize_(img(1:app.z1, 1:app.z1));
            ims(:,:,2) = imresize_(img(1:app.z1, ...
                app.z1-app.z2:app.z1-app.z2+app.z0));
            ims(:,:,3) = imresize_(img(1:app.z1, end-app.z1:end));
            
            ims(:,:,4) = imresize_(img(app.z1-app.z2:app.z1-app.z2+app.z0,...
                1:app.z1));
            ims(:,:,5) = imresize_(img(app.z1-app.z2:app.z1-app.z2+app.z0,...
                app.z1-app.z2:app.z1-app.z2+app.z0));
            ims(:,:,6) = imresize_(img(app.z1-app.z2:app.z1-app.z2+app.z0,...
                end-app.z1:end));
            
            ims(:,:,7) = imresize_(img(end-app.z1:end,1:app.z1));
            ims(:,:,8) = imresize_(img(end-app.z1:end,...
                app.z1-app.z2:app.z1-app.z2+app.z0));
            ims(:,:,9) = imresize_(img(end-app.z1:end, end-app.z1:end));
            
            function im_ = imresize_(im)
                im_ = imresize(im,[512,512]);
                im_ = imadjust_(im_);
            end
        end
        
    end
    
    methods (Access = private)
        % about process

        function semiProcess1(app)
            % 16x,semi
            
            app.getIm16();
            app.display16();
            result = num2str(func_sigDetctor16(app.im16),'%d'); % char
            app.changeSwitchsValue16(result);
            
            app.enableOKButton('on');
            app.waitForOK();
            
            app.output = app.groupSwitchValue;
            app.enableOKButton('off');
        end
        
        function autoProcess1(app)
            % 16x,auto
            
            app.getIm16();

            result = num2str(func_sigDetctor16(app.im16),'%d'); % char
            app.changeSwitchsValue16(result);
            
            app.output = result;
        end
    
        function manualProcess1(app)
            % 16x,manual
            
            app.getIm16();
            app.display16();
            app.changeSwitchsValue16('000000000');
            
            app.enableOKButton('on');
            app.waitForOK();
            
            app.output = app.groupSwitchValue;
            app.enableOKButton('off');
        end
        
        function semiProcess0(app)
            % 40x,semi
            
            % temp method
            app.getIm40();
            
            if max(max(app.im40))>=300
                app.output = '1';
            elseif max(max(app.im40))<=120
                app.output = '0';
            else
                app.changeSwitchsValue40('0');
                app.display40();
                app.enableOKButton('on');
                app.waitForOK();
                app.output = app.S8.Value;
                app.enableOKButton('off');
            end
        end
    
        function autoProcess0(app)
            % 40x,auto
            app.semiProcess0();
        end
    
        function manualProcess0(app)
            % 40x,manual
            app.semiProcess0();
        end
    end
    
    methods (Access = public)
        
        function createTCPObj(app)
            if ~isempty(app.t)
                return
            end
            app.t = tcpip('127.0.0.1', 5001, 'NetworkRole', 'server',...
                'OutputBufferSize',1000,'InputBufferSize',512*512*2*1000,...
                'Timeout',24*40*60);
        end    
    
        function getMag(app)
            if isempty(app.t)
                return
            end
            
            magLast = app.mag;
            app.mag = fread(app.t,1,'uint16');
            
            if app.mag
                app.XLabel.Text = '16X';
                if isempty(magLast) || ~magLast
                    app.swtichAvi('on');
                end
            else
                app.XLabel.Text = '40X';
                if isempty(magLast) || magLast
                    app.swtichAvi('off');
                end
            end
        end
    
        function main(app)
            while app.StartButton.Value
                if ~app.t.BytesAvailable
                    pause(0.01);
                    continue
                end
                
                app.turnonNewImLamp();
                app.OutputEditField.Value = '';
                app.getMag();
                
                func = str2func(sprintf('%sProcess%d',app.getMode(),app.mag));
                func(app);
                
                app.OutputEditField.Value = app.output;
                fwrite(app.t,app.output);
                app.turnoffNewImLamp();
            end
        end

    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.createTCPObj();
            app.getZ();
            app.axPosition = app.UIAxes.Position;
            app.SignalDetector_v1.Name = 'SignalDetector_v1.0';
        end

        % Value changed function: S1
        function S1ValueChanged(app, event)
            app.L1.Color = app.changeLampColor(app.S1.Value);
        end

        % Value changed function: S2
        function S2ValueChanged(app, event)
            app.L2.Color = app.changeLampColor(app.S2.Value);
        end

        % Value changed function: S3
        function S3ValueChanged(app, event)
            app.L3.Color = app.changeLampColor(app.S3.Value);
        end

        % Value changed function: S4
        function S4ValueChanged(app, event)
            app.L4.Color = app.changeLampColor(app.S4.Value);
        end

        % Value changed function: S5
        function S5ValueChanged(app, event)
            app.L5.Color = app.changeLampColor(app.S5.Value);
        end

        % Value changed function: S6
        function S6ValueChanged(app, event)
            app.L6.Color = app.changeLampColor(app.S6.Value);
        end

        % Value changed function: S7
        function S7ValueChanged(app, event)
            app.L7.Color = app.changeLampColor(app.S7.Value);
        end

        % Value changed function: S8
        function S8ValueChanged(app, event)
            app.L8.Color = app.changeLampColor(app.S8.Value);
        end

        % Value changed function: S9
        function S9ValueChanged(app, event)
            app.L9.Color = app.changeLampColor(app.S9.Value);
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            if isempty(app.t) || isequal(app.t.Status,'closed')
                errordlg('Establish TCP conncetion first.')
                app.StartButton.Value = 0;
                return
            end
            
            if app.StartButton.Value
                % running
                app.StartButton.Text = 'Stop';
                
                % run main loop
                app.main();
            else
                app.StartButton.Text = 'Start';
                
                % clear buffer after out loop
                app.ClearTCPBufferButtonPushed();
            end
        end

        % Value changed function: TCPCtrl
        function TCPCtrlValueChanged(app, event)
            v = str2double(app.TCPCtrl.Value);
            if v
                fopen(app.t);
            else
                fclose(app.t);
            end
        end

        % Button pushed function: ClearTCPBufferButton
        function ClearTCPBufferButtonPushed(app, event)
            if isempty(app.t)
                return
            end
            if ~app.t.BytesAvailable
                return
            end
            fread(app.t,app.t.BytesAvailable);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.fNext = 1;
        end

        % Button pushed function: All0Button
        function All0ButtonPushed(app, event)
            app.changeSwitchsValue16('000000000');
        end

        % Button pushed function: All1Button
        function All1ButtonPushed(app, event)
            app.changeSwitchsValue16('111111111');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SignalDetector_v1 = uifigure;
            app.SignalDetector_v1.Name = 'UI Figure';
            app.S1 = uiswitch(app.SignalDetector_v1, 'slider');
            app.S1.Items = {'', ''};
            app.S1.ItemsData = {'0', '1'};
            app.S1.ValueChangedFcn = createCallbackFcn(app, @S1ValueChanged, true);
            app.S1.Value = '0';
            app.L1 = uilamp(app.SignalDetector_v1);
            app.L2 = uilamp(app.SignalDetector_v1);
            app.S2 = uiswitch(app.SignalDetector_v1, 'slider');
            app.S2.Items = {'', ''};
            app.S2.ItemsData = {'0', '1'};
            app.S2.ValueChangedFcn = createCallbackFcn(app, @S2ValueChanged, true);
            app.S2.Value = '0';
            app.L3 = uilamp(app.SignalDetector_v1);
            app.S3 = uiswitch(app.SignalDetector_v1, 'slider');
            app.S3.Items = {'', ''};
            app.S3.ItemsData = {'0', '1'};
            app.S3.ValueChangedFcn = createCallbackFcn(app, @S3ValueChanged, true);
            app.S3.Value = '0';
            app.L4 = uilamp(app.SignalDetector_v1);
            app.S4 = uiswitch(app.SignalDetector_v1, 'slider');
            app.S4.Items = {'', ''};
            app.S4.ItemsData = {'0', '1'};
            app.S4.ValueChangedFcn = createCallbackFcn(app, @S4ValueChanged, true);
            app.S4.Value = '0';
            app.L5 = uilamp(app.SignalDetector_v1);
            app.S5 = uiswitch(app.SignalDetector_v1, 'slider');
            app.S5.Items = {'', ''};
            app.S5.ItemsData = {'0', '1'};
            app.S5.ValueChangedFcn = createCallbackFcn(app, @S5ValueChanged, true);
            app.S5.Value = '0';
            app.L6 = uilamp(app.SignalDetector_v1);
            app.S6 = uiswitch(app.SignalDetector_v1, 'slider');
            app.S6.Items = {'', ''};
            app.S6.ItemsData = {'0', '1'};
            app.S6.ValueChangedFcn = createCallbackFcn(app, @S6ValueChanged, true);
            app.S6.Value = '0';
            app.L7 = uilamp(app.SignalDetector_v1);
            app.S7 = uiswitch(app.SignalDetector_v1, 'slider');
            app.S7.Items = {'', ''};
            app.S7.ItemsData = {'0', '1'};
            app.S7.ValueChangedFcn = createCallbackFcn(app, @S7ValueChanged, true);
            app.S7.Value = '0';
            app.L8 = uilamp(app.SignalDetector_v1);
            app.S8 = uiswitch(app.SignalDetector_v1, 'slider');
            app.S8.Items = {'', ''};
            app.S8.ItemsData = {'0', '1'};
            app.S8.ValueChangedFcn = createCallbackFcn(app, @S8ValueChanged, true);
            app.S8.Value = '0';
            app.L9 = uilamp(app.SignalDetector_v1);
            app.S9 = uiswitch(app.SignalDetector_v1, 'slider');
            app.S9.Items = {'', ''};
            app.S9.ItemsData = {'0', '1'};
            app.S9.ValueChangedFcn = createCallbackFcn(app, @S9ValueChanged, true);
            app.S9.Value = '0';
            app.UIAxes = uiaxes(app.SignalDetector_v1);
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.ModeButtonGroup = uibuttongroup(app.SignalDetector_v1);
            app.ModeButtonGroup.Title = 'Mode';
            app.SemiButton = uiradiobutton(app.ModeButtonGroup);
            app.SemiButton.Text = 'Semi';
            app.SemiButton.Value = true;
            app.AutoButton = uiradiobutton(app.ModeButtonGroup);
            app.AutoButton.Text = 'Auto';
            app.ManualButton = uiradiobutton(app.ModeButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.TCPCtrl = uiswitch(app.SignalDetector_v1, 'toggle');
            app.TCPCtrl.Items = {'Close', 'Open'};
            app.TCPCtrl.ItemsData = {'0', '1'};
            app.TCPCtrl.ValueChangedFcn = createCallbackFcn(app, @TCPCtrlValueChanged, true);
            app.TCPCtrl.Value = '0';
            app.TCPControlLabel = uilabel(app.SignalDetector_v1);
            app.TCPControlLabel.Text = 'TCP Control';
            app.ClearTCPBufferButton = uibutton(app.SignalDetector_v1, 'push');
            app.ClearTCPBufferButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTCPBufferButtonPushed, true);
            app.ClearTCPBufferButton.Text = 'Clear TCP Buffer';
            app.OKButton = uibutton(app.SignalDetector_v1, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Tooltip = {'Only work when semi and manual mode'};
            app.OKButton.Text = 'OK';
            app.StartButton = uibutton(app.SignalDetector_v1, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.NewLampLabel = uilabel(app.SignalDetector_v1);
            app.NewLampLabel.Text = 'New';
            app.NewLamp = uilamp(app.SignalDetector_v1);
            app.XLabel = uilabel(app.SignalDetector_v1);
            app.XLabel.Text = '16X';
            app.All0Button = uibutton(app.SignalDetector_v1, 'push');
            app.All0Button.ButtonPushedFcn = createCallbackFcn(app, @All0ButtonPushed, true);
            app.All0Button.Text = 'All 0';
            app.All1Button = uibutton(app.SignalDetector_v1, 'push');
            app.All1Button.ButtonPushedFcn = createCallbackFcn(app, @All1ButtonPushed, true);
            app.All1Button.Text = 'All 1';
            app.OutputEditFieldLabel = uilabel(app.SignalDetector_v1);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.SignalDetector_v1, 'text');
            app.OutputEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SignalDetector

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SignalDetector_v1)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalDetector_v1)
        end
    end
end