classdef SignalDetector_v1_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalDetector_v1       matlab.ui.Figure
        L1                      matlab.ui.control.Lamp
        L2                      matlab.ui.control.Lamp
        L3                      matlab.ui.control.Lamp
        L4                      matlab.ui.control.Lamp
        L5                      matlab.ui.control.Lamp
        L6                      matlab.ui.control.Lamp
        L7                      matlab.ui.control.Lamp
        L8                      matlab.ui.control.Lamp
        L9                      matlab.ui.control.Lamp
        UIAxes                  matlab.ui.control.UIAxes
        ModeButtonGroup         matlab.ui.container.ButtonGroup
        SemiButton              matlab.ui.control.RadioButton
        AutoButton              matlab.ui.control.RadioButton
        ManualButton            matlab.ui.control.RadioButton
        TCPCtrl                 matlab.ui.control.ToggleSwitch
        TCPControlLabel         matlab.ui.control.Label
        ClearBufferButton       matlab.ui.control.Button
        OKButton                matlab.ui.control.Button
        StartButton             matlab.ui.control.StateButton
        NewLampLabel            matlab.ui.control.Label
        NewLamp                 matlab.ui.control.Lamp
        XLabel                  matlab.ui.control.Label
        All0Button              matlab.ui.control.Button
        All1Button              matlab.ui.control.Button
        OutputEditFieldLabel    matlab.ui.control.Label
        OutputEditField         matlab.ui.control.EditField
        BrowseButton            matlab.ui.control.Button
        EditField               matlab.ui.control.EditField
        XThreLEditFieldLabel    matlab.ui.control.Label
        XThreLEditField         matlab.ui.control.NumericEditField
        XThreHEditFieldLabel    matlab.ui.control.Label
        XThreHEditField         matlab.ui.control.NumericEditField
        XMaxEditFieldLabel      matlab.ui.control.Label
        XMaxEditField           matlab.ui.control.NumericEditField
        B1                      matlab.ui.control.Button
        B2                      matlab.ui.control.Button
        B3                      matlab.ui.control.Button
        B4                      matlab.ui.control.Button
        B5                      matlab.ui.control.Button
        B6                      matlab.ui.control.Button
        B7                      matlab.ui.control.Button
        B8                      matlab.ui.control.Button
        B9                      matlab.ui.control.Button
        Back1imButton           matlab.ui.control.Button
        UndockimButton          matlab.ui.control.Button
        XThreLEditFieldLabel_2  matlab.ui.control.Label
        XThre16EditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        LampGroup % L1 - L9
        SwitchGroup % S1 - S9
        buttonGroup; %B1-9
        
        t % tcp object
        
        mag % 1 16X; 0 40X
        
        fNext = 0;
        
        resizeN = 512;
    end
    
    properties (Access = private, Constant)
        prefixOutputs16 = ['w','o']; 
        % w: write 9 numbers, o: overwrite 9 number; 
        % need process in client program 
        
        terminalIn16 = 2; % 40 0, 16 1
        terminalOut16 = repmat('q',1,10); % server back to say 16x finish
    end
    
    properties (Access = private)
        LampColors = [0.8,0.8,0.8;0.39,0.83,0.07];

        re16 = 1.0893;
        re40 = 0.4651;
        
        olp40 = 0.25;
        olp16
        z0
        z1
        z2
        
        im16
        im40
        im40z
        
        last16 % back 1 im use
        queued16 % back 1 im use
        prefixOutput16
        
        axPosition;
        
        output;
        
        keyIdx = [7,8,9,4,5,6,1,2,3];
    end
    
    methods (Access = private)
        % about ui ctrl
        function groupLamp(app)
            app.LampGroup = [app.L1,app.L2,app.L3,...
                app.L4,app.L5,app.L6,...
                app.L7,app.L8,app.L9];
        end
    
        function v = groupSwitchValue(app)
            %{
            v = [app.S1.Value,app.S2.Value,app.S3.Value,...
                app.S4.Value,app.S5.Value,app.S6.Value,...
                app.S7.Value,app.S8.Value,app.S9.Value];
            %}
            v = '000000000';
            for i = 1:9
                v(i) = num2str(app.buttonGroup(i).UserData.v);
            end
        end
    
        function iniButtionsGrop(app)
            app.buttonGroup = [app.B1,app.B2,app.B3,...
                app.B4,app.B5,app.B6,...
                app.B7,app.B8,app.B9];
            for i = 1:9
                app.buttonGroup(i).UserData.v = 0;
                app.buttonGroup(i).UserData.l = app.LampGroup(i);
            end
        end
        
        function generalButtonLampCallback(app,event)
            if isempty(event)
                st = dbstack;
                str = regexp(st(2).name,'B\d','match');
                event.Source = eval(['app.',str{:}]);
            end
            event.Source.UserData.v = ~event.Source.UserData.v;
            event.Source.UserData.l.Color = ...
                app.changeLampColor(event.Source.UserData.v);
        end
    
        function c = changeLampColor(app,v)
            if ischar(v)
                c = app.LampColors(str2double(v)+1,:);
            else
                c = app.LampColors(v+1,:);
            end
        end
        
        function swtichAvi(app,onoff)
            for i = [1:7,9]
                individualCtrl(app.LampGroup(i));
                individualCtrl(app.buttonGroup(i));
            end
            
            function individualCtrl(uiObj)
                uiObj.Enable = onoff;
            end
        end
        
        function changeSwitchsValue16(app,result)
            % result is char
            for i =1:9
                app.buttonGroup(i).UserData.v = ~str2double(result(i));
                func = str2func(num2str(i,'B%dButtonPushed'));
                func(app,[]);
            end
        end
        
        function changeSwitchsValue40(app,result)
            %{
            app.S8.Value = result;
            app.S8ValueChanged();
            %}
            app.B8.UserData.v = ~str2double(result);
            app.B8ButtonPushed([]);
        end
    
        function turnonNewImLamp(app)
            app.NewLamp.Color = app.LampColors(2,:);
        end
        
        function turnoffNewImLamp(app)
            app.NewLamp.Color = app.LampColors(1,:);
        end
    
        function mode = getMode(app)
            selectedButton = app.ModeButtonGroup.SelectedObject;
            mode = lower(selectedButton.Text);
        end
    
        function enableOKButton(app,onoff)
            app.OKButton.Enable = onoff;
        end
    
        function enableBack1ImButton(app,onoff)
            app.Back1imButton.Enable = onoff;
        end
        
        function enableUndockButton(app,onoff)
            app.UndockimButton.Enable = onoff;
        end
    end
    
    methods (Access = private)
        % about process related
        
        function getZ(app)
            app.olp16 = 1 - 3 * ( 1 - app.olp40) * app.re40 / app.re16;
            app.z0 = round(app.re40 *1200 / app.re16);
            app.z1 = round((0.5 * app.re40 + ...
                (app.olp40 - 1) * app.re40 + 0.5 * app.re16) * 1200 / app.re16);
            app.z2 = round(app.olp40 * app.re40 *1200 / app.re16);
        end
    
        function getIm16(app)
            if isempty(app.queued16)
                im_1 = app.imCheck0(fread(app.t,1200*1200,'uint16'));
                app.im16 = uint16(reshape(im_1',[1200,1200]));
            else
                app.im16 = app.queued16;
                app.queued16 = [];
            end
        end
        
        function getIm40(app)
            n = fread(app.t,2,'uint16');
            im_1 = app.imCheck0(fread(app.t,prod(n),'uint16'));
            app.im40 = uint16(reshape(im_1',n')); % 251 * 1200
            
            n = fread(app.t,2,'uint16');
            im_2 = app.imCheck0(fread(app.t,prod(n),'uint16'));
            app.im40z = uint16(reshape(im_2',n')); % 60 * 1200
        end
        
        function im = imCheck0(app,im)
            % im is flatten first
            
            if all(im)
                return
            end
            
            im(im==0) = min(min(im));
        end
    
        function display16(app,fUndock)
            if nargin<2
                fUndock = 0;
            end
            if fUndock
                figure;
                ax = axes();
            else
                ax = app.UIAxes;
                beep2;
            end
            montage(repmat(reshape(...
                app.imSegment(app.im16),app.resizeN,app.resizeN,1,9),1,1,3,1),...
                'Parent',ax,'ThumbnailSize',[]);
            %app.UIAxes.Position= app.axPosition;
            %axis(app.UIAxes,'square');
            %axis(app.UIAxes,'equal');
            axis(app.UIAxes,'image');
            app.UIAxes.Visible =  'on';
            app.UIAxes.TickLength = [0 0];
            app.UIAxes.Box = 'on';
        end
        
        function displaySigPosition(app,sigPos)
            nMax = 3000;
            shift = [0,0;...
                app.resizeN,0;...
                app.resizeN*2,0;...
                0,app.resizeN;...
                app.resizeN,app.resizeN;...
                app.resizeN*2,app.resizeN;...
                0,app.resizeN*2;...
                app.resizeN,app.resizeN*2;...
                app.resizeN*2,app.resizeN*2];
            for ii = 1:size(sigPos,1)
                sigPos{ii} = sigPos{ii} + shift(ii,:);
            end
            sigPos = cell2mat(sigPos);
            if size(sigPos,1)>nMax
                sigPos_ = sigPos(randsample(size(sigPos,1),nMax),:);
            else
                sigPos_ = sigPos;
            end
            app.UIAxes.NextPlot = 'add';
            scatter(app.UIAxes,sigPos_(:,1),sigPos_(:,2),'r+');
            app.UIAxes.NextPlot = 'replacechildren';
        end
    
        function display40(app,fUndock)
            if nargin<2
                fUndock = 0;
            end
            if fUndock
                figure;
                ax = axes();
            else
                ax = app.UIAxes;
                beep2;
            end
            imshow([app.im40;nan(10,size(app.im40,2));app.im40z],...
                [100,app.XThreHEditField.Value],'Parent',ax);
            [~,ind] = max(app.im40(:));
            [rowi,coli] = ind2sub(size(app.im40),ind);
            rectangle(ax,'Position',[coli-25,rowi-25,50,50],'EdgeColor','r');
            %app.UIAxes.Position= app.axPosition;
            axis(app.UIAxes,'image');
            app.UIAxes.Visible =  'on';
            app.UIAxes.TickLength = [0 0];
            app.UIAxes.Box = 'on';
        end
        
        function waitForOK(app)
            waitfor(app,'fNext',1);
            app.fNext = 0;
        end
        
        function ims = imSegment(app,img)
            ims = zeros(app.resizeN,app.resizeN,9,'uint16');
                        
            ims(:,:,1) = imresize_(img(1:app.z1, 1:app.z1));
            ims(:,:,2) = imresize_(img(1:app.z1, ...
                app.z1-app.z2:app.z1-app.z2+app.z0));
            ims(:,:,3) = imresize_(img(1:app.z1, end-app.z1:end));
            
            ims(:,:,4) = imresize_(img(app.z1-app.z2:app.z1-app.z2+app.z0,...
                1:app.z1));
            ims(:,:,5) = imresize_(img(app.z1-app.z2:app.z1-app.z2+app.z0,...
                app.z1-app.z2:app.z1-app.z2+app.z0));
            ims(:,:,6) = imresize_(img(app.z1-app.z2:app.z1-app.z2+app.z0,...
                end-app.z1:end));
            
            ims(:,:,7) = imresize_(img(end-app.z1:end,1:app.z1));
            ims(:,:,8) = imresize_(img(end-app.z1:end,...
                app.z1-app.z2:app.z1-app.z2+app.z0));
            ims(:,:,9) = imresize_(img(end-app.z1:end, end-app.z1:end));
            
            function im_ = imresize_(im)
                im_ = imresize(im,[app.resizeN,app.resizeN]);
                im_ = imadjust_(im_);
            end
        end
        
        function save40(app)
            if ~app.mag && ~isempty(app.EditField.Value)
                try
                    n = numel(dir(fullfile(app.EditField.Value,'*.tif')));
                    func_writeTifFast16(app.im40,...
                        fullfile(app.EditField.Value,...
                        sprintf('%06d_%s.tif',n+1,app.output)));
                catch
                end
            end
        end
    
        function value = getMax40(app)
            value = mean(maxk(app.im40(:),2));
        end
    
        function res = getCombinedResult16(app,res)
            res = [app.prefixOutput16,res];
        end
    end
    
    methods (Access = private)
        % about process
        
        function generalProcess1(app)
            app.enableBack1ImButton('on');
            app.enableOKButton('on');
            app.enableUndockButton('on');
            app.waitForOK();
            
            app.last16 = app.im16;
            
            app.output = app.getCombinedResult16(app.groupSwitchValue());
            app.enableOKButton('off');
            app.enableBack1ImButton('off');
            app.enableUndockButton('off');
        end
        
        function semiProcess1(app)
            % 16x,semi
            
            app.prefixOutput16 = app.prefixOutputs16(1);
            app.getIm16();
            app.display16();
            [result,sigPos] = func_sigDetctor16(app.im16,...
                app.XThre16EditField.Value);
            result = num2str(result,'%d'); % char
            %app.displaySigPosition(sigPos); % bug now
            app.changeSwitchsValue16(result);
            
            app.generalProcess1();
        end
        
        function autoProcess1(app)
            % 16x,auto
            
            app.getIm16();

            result = num2str(func_sigDetctor16(app.im16,...
                app.XThre16EditField.Value),'%d'); % char
            app.changeSwitchsValue16(result);
            
            app.output = app.getCombinedResult16(result);
            app.last16 = app.im16;
        end
    
        function manualProcess1(app)
            % 16x,manual
            
            app.prefixOutput16 = app.prefixOutputs16(1);
            app.getIm16();
            app.display16();
            app.changeSwitchsValue16('000000000');
            
            app.generalProcess1();
        end
        
        function semiProcess0(app)
            % 40x,semi
            
            % temp method
            app.getIm40();
            
            maxValue = app.getMax40();
            app.XMaxEditField.Value = maxValue;
            if maxValue>=app.XThreHEditField.Value
                app.output = '1';
            elseif maxValue<=app.XThreLEditField.Value
                app.output = '0';
            else
                app.changeSwitchsValue40('0');
                app.display40();
                app.enableOKButton('on');
                app.enableUndockButton('on');
                app.waitForOK();
                %app.output = app.S8.Value;
                app.output = num2str(app.B8.UserData.v);
                app.enableOKButton('off');
                app.enableUndockButton('off');
            end
        end
    
        function autoProcess0(app)
            % 40x,auto
            app.semiProcess0();
        end
    
        function manualProcess0(app)
            % 40x,manual
            app.semiProcess0();
        end
    end
    
    methods (Access = public)
        
        function createTCPObj(app)
            if ~isempty(app.t)
                return
            end
            app.t = tcpip('127.0.0.1', 5001, 'NetworkRole', 'server',...
                'OutputBufferSize',1000,'InputBufferSize',app.resizeN*app.resizeN*4*1000,...
                'Timeout',24*40*60);
        end    
    
        function fContinue = getMag(app)
            if isempty(app.t)
                return
            end
            
            magLast = app.mag;
            fContinue = 0;
            
            if isempty(app.queued16)
                receivedNum = fread(app.t,1,'uint16');
                if ismember(receivedNum,[0,1])
                    app.mag = receivedNum;
                elseif receivedNum==app.terminalIn16
                    % note last 16x im can not be overwrited!!!!!!!!!!
                    fwrite(app.t,app.terminalOut16);
                    fContinue = 1;
                    return
                end
            else
                app.mag = 1; % 2019/7/7, only support 16x now
            end
            
            if app.mag
                app.XLabel.Text = '16X';
                if isempty(magLast) || ~magLast
                    app.swtichAvi('on');
                end
            else
                app.XLabel.Text = '40X';
                if isempty(magLast) || magLast
                    app.swtichAvi('off');
                    app.Back1imButton.Enable = 'off'; % back 1 im use, only for 16x now
                end
            end
        end
        
        function tf = isIdle(app)
            tf = ~app.t.BytesAvailable && isempty(app.queued16);
        end
        
        function main(app)
            while app.StartButton.Value
                if app.isIdle()
                    pause(0.01);
                    continue
                end
                
                app.OutputEditField.Value = '';
                fContinue = app.getMag();
                if fContinue
                    % send 16x finished terminal
                    continue
                end
                app.turnonNewImLamp();
                
                func = str2func(sprintf('%sProcess%d',app.getMode(),app.mag));
                func(app);
                
                app.OutputEditField.Value = app.output;
                fwrite(app.t,app.output);
                app.turnoffNewImLamp();
                
                app.save40();
            end
        end

    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.createTCPObj();
            app.getZ();
            app.axPosition = app.UIAxes.Position;
            app.SignalDetector_v1.Name = 'SignalDetector_v1.4';
            app.OKButton.Tooltip = '';
            app.XThreLEditField.Value = 120;
            app.XThreHEditField.Value = 300;
            app.XThre16EditField.Value = 6;
            
            app.groupLamp();
            app.iniButtionsGrop();
        end

        % Callback function
        function S1ValueChanged(app, event)
            app.L1.Color = app.changeLampColor(app.S1.Value);
        end

        % Callback function
        function S2ValueChanged(app, event)
            app.L2.Color = app.changeLampColor(app.S2.Value);
        end

        % Callback function
        function S3ValueChanged(app, event)
            app.L3.Color = app.changeLampColor(app.S3.Value);
        end

        % Callback function
        function S4ValueChanged(app, event)
            app.L4.Color = app.changeLampColor(app.S4.Value);
        end

        % Callback function
        function S5ValueChanged(app, event)
            app.L5.Color = app.changeLampColor(app.S5.Value);
        end

        % Callback function
        function S6ValueChanged(app, event)
            app.L6.Color = app.changeLampColor(app.S6.Value);
        end

        % Callback function
        function S7ValueChanged(app, event)
            app.L7.Color = app.changeLampColor(app.S7.Value);
        end

        % Callback function
        function S8ValueChanged(app, event)
            app.L8.Color = app.changeLampColor(app.S8.Value);
        end

        % Callback function
        function S9ValueChanged(app, event)
            app.L9.Color = app.changeLampColor(app.S9.Value);
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            if isempty(app.t) || isequal(app.t.Status,'closed')
                errordlg('Establish TCP conncetion first.')
                app.StartButton.Value = 0;
                return
            end
            
            if app.StartButton.Value
                % running
                app.StartButton.Text = 'Stop';
                
                % run main loop
                app.main();
            else
                app.StartButton.Text = 'Start';
                
                % clear buffer after out loop
                app.ClearBufferButtonPushed();
            end
        end

        % Value changed function: TCPCtrl
        function TCPCtrlValueChanged(app, event)
            v = str2double(app.TCPCtrl.Value);
            if v
                fopen(app.t);
            else
                fclose(app.t);
            end
        end

        % Button pushed function: ClearBufferButton
        function ClearBufferButtonPushed(app, event)
            if isempty(app.t)
                return
            end
            if ~app.t.BytesAvailable
                return
            end
            fread(app.t,app.t.BytesAvailable);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.fNext = 1;
        end

        % Button pushed function: All0Button
        function All0ButtonPushed(app, event)
            app.changeSwitchsValue16('000000000');
        end

        % Button pushed function: All1Button
        function All1ButtonPushed(app, event)
            app.changeSwitchsValue16('111111111');
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            filePath = uigetdir();
            if filePath
                app.EditField.Value = filePath;
            else
                app.EditField.Value = '';
            end
        end

        % Button pushed function: B1
        function B1ButtonPushed(app, event)
            app.generalButtonLampCallback(event);
        end

        % Button pushed function: B2
        function B2ButtonPushed(app, event)
            app.generalButtonLampCallback(event);
        end

        % Button pushed function: B3
        function B3ButtonPushed(app, event)
            app.generalButtonLampCallback(event);
        end

        % Button pushed function: B4
        function B4ButtonPushed(app, event)
            app.generalButtonLampCallback(event);
        end

        % Button pushed function: B5
        function B5ButtonPushed(app, event)
            app.generalButtonLampCallback(event);
        end

        % Button pushed function: B6
        function B6ButtonPushed(app, event)
            app.generalButtonLampCallback(event);
        end

        % Button pushed function: B7
        function B7ButtonPushed(app, event)
            app.generalButtonLampCallback(event);
        end

        % Button pushed function: B8
        function B8ButtonPushed(app, event)
            app.generalButtonLampCallback(event);
        end

        % Button pushed function: B9
        function B9ButtonPushed(app, event)
            app.generalButtonLampCallback(event);
        end

        % Key press function: SignalDetector_v1
        function SignalDetector_v1KeyPress(app, event)
            if isequal(app.NewLamp.Color,app.LampColors(1,:)) ||...
                    isequal(app.getMode(),'auto')
                return
            end
            
            key = event.Key;
            switch key
                % keyboard dependent
                case {'1','2','3','4','5','6','7','8','9',...
                        'numpad1','numpad2','numpad3','numpad4',...
                        'numpad5','numpad6','numpad7','numpad8','numpad9'}
                    if app.mag
                        % 16x
                        func = str2func(...
                            num2str(app.keyIdx(str2double(key(end))),...
                            'B%dButtonPushed'));
                        func(app,[]);
                    else
                        app.B8ButtonPushed([]);
                    end
                case {'subtract','hyphen'}
                    % -
                    app.All0ButtonPushed();
                case {'add','equal'}
                    app.All1ButtonPushed();
                case 'return'
                    app.OKButtonPushed();
                case {'decimal','period'}
                    if isequal(app.Back1imButton.Enable,'on')
                        app.Back1imButtonPushed();
                    end
            end
        end

        % Button pushed function: Back1imButton
        function Back1imButtonPushed(app, event)
            % only 16x now
            % % note last 16x im can not be overwrited!!!!!!!!!!
            
            if ~isempty(app.queued16) || isempty(app.last16)
                % if not empty queue will not work 
                msgbox('This is last image. Can only go back for 1 image.');
                return
            end
            
            app.queued16 = app.im16; % queue the current im
            app.im16 = app.last16;
            
            app.display16();
            app.changeSwitchsValue16('000000000');
            
            app.prefixOutput16 = app.prefixOutputs16(2); % overwrite
        end

        % Close request function: SignalDetector_v1
        function SignalDetector_v1CloseRequest(app, event)
            selection = questdlg('Close This Figure?',...
                'Close Request Function',...
                'Yes','Cancel','Cancel');
            switch selection
                case 'Yes'
                    delete(app)
                case 'Cancel'
                    return
            end
        end

        % Button pushed function: UndockimButton
        function UndockimButtonPushed(app, event)
            if app.mag
                disp_ = @app.display16;
            else
                disp_ = @app.display40;
            end
            disp_(1);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SignalDetector_v1 = uifigure;
            app.SignalDetector_v1.Name = 'UI Figure';
            app.SignalDetector_v1.CloseRequestFcn = createCallbackFcn(app, @SignalDetector_v1CloseRequest, true);
            app.SignalDetector_v1.KeyPressFcn = createCallbackFcn(app, @SignalDetector_v1KeyPress, true);
            app.L1 = uilamp(app.SignalDetector_v1);
            app.L2 = uilamp(app.SignalDetector_v1);
            app.L3 = uilamp(app.SignalDetector_v1);
            app.L4 = uilamp(app.SignalDetector_v1);
            app.L5 = uilamp(app.SignalDetector_v1);
            app.L6 = uilamp(app.SignalDetector_v1);
            app.L7 = uilamp(app.SignalDetector_v1);
            app.L8 = uilamp(app.SignalDetector_v1);
            app.L9 = uilamp(app.SignalDetector_v1);
            app.UIAxes = uiaxes(app.SignalDetector_v1);
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.ModeButtonGroup = uibuttongroup(app.SignalDetector_v1);
            app.ModeButtonGroup.Title = 'Mode';
            app.SemiButton = uiradiobutton(app.ModeButtonGroup);
            app.SemiButton.Text = 'Semi';
            app.AutoButton = uiradiobutton(app.ModeButtonGroup);
            app.AutoButton.Text = 'Auto';
            app.ManualButton = uiradiobutton(app.ModeButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.TCPCtrl = uiswitch(app.SignalDetector_v1, 'toggle');
            app.TCPCtrl.Items = {'Close', 'Open'};
            app.TCPCtrl.ItemsData = {'0', '1'};
            app.TCPCtrl.ValueChangedFcn = createCallbackFcn(app, @TCPCtrlValueChanged, true);
            app.TCPCtrl.Value = '0';
            app.TCPControlLabel = uilabel(app.SignalDetector_v1);
            app.TCPControlLabel.Text = 'TCP Control';
            app.ClearBufferButton = uibutton(app.SignalDetector_v1, 'push');
            app.ClearBufferButton.ButtonPushedFcn = createCallbackFcn(app, @ClearBufferButtonPushed, true);
            app.ClearBufferButton.Text = 'Clear Buffer';
            app.OKButton = uibutton(app.SignalDetector_v1, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Enable = 'off';
            app.OKButton.Tooltip = {' '};
            app.OKButton.Text = 'OK';
            app.StartButton = uibutton(app.SignalDetector_v1, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.NewLampLabel = uilabel(app.SignalDetector_v1);
            app.NewLampLabel.Text = 'New';
            app.NewLamp = uilamp(app.SignalDetector_v1);
            app.XLabel = uilabel(app.SignalDetector_v1);
            app.XLabel.Text = '16X';
            app.All0Button = uibutton(app.SignalDetector_v1, 'push');
            app.All0Button.ButtonPushedFcn = createCallbackFcn(app, @All0ButtonPushed, true);
            app.All0Button.Text = 'All 0';
            app.All1Button = uibutton(app.SignalDetector_v1, 'push');
            app.All1Button.ButtonPushedFcn = createCallbackFcn(app, @All1ButtonPushed, true);
            app.All1Button.Text = 'All 1';
            app.OutputEditFieldLabel = uilabel(app.SignalDetector_v1);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.SignalDetector_v1, 'text');
            app.OutputEditField.Editable = 'off';
            app.BrowseButton = uibutton(app.SignalDetector_v1, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.EditField = uieditfield(app.SignalDetector_v1, 'text');
            app.XThreLEditFieldLabel = uilabel(app.SignalDetector_v1);
            app.XThreLEditFieldLabel.Text = '40X Thre. L';
            app.XThreLEditField = uieditfield(app.SignalDetector_v1, 'numeric');
            app.XThreHEditFieldLabel = uilabel(app.SignalDetector_v1);
            app.XThreHEditFieldLabel.Text = '40X Thre. H';
            app.XThreHEditField = uieditfield(app.SignalDetector_v1, 'numeric');
            app.XMaxEditFieldLabel = uilabel(app.SignalDetector_v1);
            app.XMaxEditFieldLabel.Text = '40X Max';
            app.XMaxEditField = uieditfield(app.SignalDetector_v1, 'numeric');
            app.XMaxEditField.Editable = 'off';
            app.B1 = uibutton(app.SignalDetector_v1, 'push');
            app.B1.ButtonPushedFcn = createCallbackFcn(app, @B1ButtonPushed, true);
            app.B1.Text = '';
            app.B2 = uibutton(app.SignalDetector_v1, 'push');
            app.B2.ButtonPushedFcn = createCallbackFcn(app, @B2ButtonPushed, true);
            app.B2.Text = '';
            app.B3 = uibutton(app.SignalDetector_v1, 'push');
            app.B3.ButtonPushedFcn = createCallbackFcn(app, @B3ButtonPushed, true);
            app.B3.Text = '';
            app.B4 = uibutton(app.SignalDetector_v1, 'push');
            app.B4.ButtonPushedFcn = createCallbackFcn(app, @B4ButtonPushed, true);
            app.B4.Text = '';
            app.B5 = uibutton(app.SignalDetector_v1, 'push');
            app.B5.ButtonPushedFcn = createCallbackFcn(app, @B5ButtonPushed, true);
            app.B5.Text = '';
            app.B6 = uibutton(app.SignalDetector_v1, 'push');
            app.B6.ButtonPushedFcn = createCallbackFcn(app, @B6ButtonPushed, true);
            app.B6.Text = '';
            app.B7 = uibutton(app.SignalDetector_v1, 'push');
            app.B7.ButtonPushedFcn = createCallbackFcn(app, @B7ButtonPushed, true);
            app.B7.Text = '';
            app.B8 = uibutton(app.SignalDetector_v1, 'push');
            app.B8.ButtonPushedFcn = createCallbackFcn(app, @B8ButtonPushed, true);
            app.B8.Text = '';
            app.B9 = uibutton(app.SignalDetector_v1, 'push');
            app.B9.ButtonPushedFcn = createCallbackFcn(app, @B9ButtonPushed, true);
            app.B9.Text = '';
            app.Back1imButton = uibutton(app.SignalDetector_v1, 'push');
            app.Back1imButton.ButtonPushedFcn = createCallbackFcn(app, @Back1imButtonPushed, true);
            app.Back1imButton.Enable = 'off';
            app.Back1imButton.Text = 'Back 1 im';
            app.UndockimButton = uibutton(app.SignalDetector_v1, 'push');
            app.UndockimButton.ButtonPushedFcn = createCallbackFcn(app, @UndockimButtonPushed, true);
            app.UndockimButton.Enable = 'off';
            app.UndockimButton.Text = 'Undock im';
            app.XThreLEditFieldLabel_2 = uilabel(app.SignalDetector_v1);
            app.XThreLEditFieldLabel_2.Text = '16X Thre. ';
            app.XThre16EditField = uieditfield(app.SignalDetector_v1, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = SignalDetector_v1_4

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SignalDetector_v1)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalDetector_v1)
        end
    end
end